classdef SpaceTimeGLOBE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridSpacingEditField       matlab.ui.control.NumericEditField
        GridSpacingEditFieldLabel  matlab.ui.control.Label
        VelocitySlider             matlab.ui.control.Slider
        VelocitySliderLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GridSpacingEditField, VelocitySlider
        function VelocitySliderValueChanged(app, event)
            v = app.VelocitySlider.Value;
            g = app.GridSpacingEditField.Value;

            % Create the grids
            x = app.UIAxes.XTick;
            t = app.UIAxes.YTick;
            gamma = 1/sqrt(1-v^2);

            % Plot the lines
            plot(app.UIAxes, 1*t, t,Color='#ffcc00', LineWidth=5);
            hold(app.UIAxes,'on');
            plot(app.UIAxes, v*t, t,'r', LineWidth=5);
            plot(app.UIAxes, x, v*x,'r', LineWidth=5);

            plot(app.UIAxes, 0*t, t,'b', LineWidth=5);
            plot(app.UIAxes, x, v*x*0,'b', LineWidth=5);

            % Create Space-Time Grids
            for k = 0:g:80
                t1 = v*(x - k) + k;
                x1 = v*(t - k) + k;
                plot(app.UIAxes, x1, t,'r',LineWidth=0.8);
                hold(app.UIAxes,'on');
                plot(app.UIAxes, x, t1,'r',LineWidth=0.8);

                t2 = 0*x + k;
                x2 = 0*t + k;
                plot(app.UIAxes, x, t2,'b',LineStyle='--', LineWidth=0.8);
                plot(app.UIAxes, x2, t,'b',LineStyle='--', LineWidth=0.8);
            end

            cursor = datacursormode(app.UIFigure);
            set(cursor, 'UpdateFcn',{@displayCoordinates})

            function txt = displayCoordinates(~, info)
                pos = info.Position;
                if pos(1) > v*pos(2) && pos(2) > v*pos(1)
                    txt = {['X = ',num2str(pos(1)),', T = ',num2str(pos(2))],['X̃ = ',num2str(pos(1)*gamma),', T̃ = ',num2str(pos(2)/gamma)]};
                else
                    txt = {['X = ',num2str(pos(1)),', T = ',num2str(pos(2))]};
                end
            end

            % Label The Axes
            text(app.UIAxes, 0*75,75,"T",Color='#12239e',FontSize=50,FontWeight="bold");
            text(app.UIAxes, 75, 0*75,"X",Color='#12239e',FontSize=50,FontWeight="bold");
            text(app.UIAxes, v*70,70,"T̃",Color='#cc0033',FontSize=50,FontWeight="bold");
            text(app.UIAxes, 70, v*70,"X̃",Color='#cc0033',FontSize=50,FontWeight="bold");
            text(app.UIAxes, 40, 40,"C'",Color='#ff9900',FontSize=50,FontWeight="bold");

            hold(app.UIAxes,'off');
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Space-Time Globe')
            xlabel(app.UIAxes, 'X(Space)')
            ylabel(app.UIAxes, 'Y(Time)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.UIAxes.YTick = [0 10 20 30 40 50 60 70 80];
            app.UIAxes.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [0 1];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.GridSpacingEditFieldLabel = uilabel(app.UIFigure);
            app.GridSpacingEditFieldLabel.Text = 'Grid Spacing';
            app.GridSpacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridSpacingEditField.Limits = [0 Inf];
            app.GridSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.GridSpacingEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpaceTimeGLOBE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end